classdef recomendSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DevopsSpinnerLabel              matlab.ui.control.Label
        DevopsSpinner                   matlab.ui.control.Spinner
        Image2_10                       matlab.ui.control.Image
        SignalProcessingSpinnerLabel    matlab.ui.control.Label
        SignalProcessingSpinner         matlab.ui.control.Spinner
        Image2_9                        matlab.ui.control.Image
        ImageProcessingSpinnerLabel     matlab.ui.control.Label
        ImageProcessingSpinner          matlab.ui.control.Spinner
        Image2_8                        matlab.ui.control.Image
        CheckBox_7                      matlab.ui.control.CheckBox
        CloudComputingLabel             matlab.ui.control.Label
        CheckBox_6                      matlab.ui.control.CheckBox
        ImageProcessingLabel            matlab.ui.control.Label
        Image2_7                        matlab.ui.control.Image
        Image2_6                        matlab.ui.control.Image
        Image2_5                        matlab.ui.control.Image
        Image2_4                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        InterestsLabel_2                matlab.ui.control.Label
        AvailableResearchAreasLabel_2   matlab.ui.control.Label
        MachineLearningSpinner          matlab.ui.control.Spinner
        Image                           matlab.ui.control.Image
        MachineLearningSpinnerLabel     matlab.ui.control.Label
        HighLevelProgrammingSpinnerLabel  matlab.ui.control.Label
        HighLevelProgrammingSpinner     matlab.ui.control.Spinner
        ComputerArchitectureSpinner     matlab.ui.control.Spinner
        LowLevelProgrammingSpinner      matlab.ui.control.Spinner
        LowLevelProgrammingSpinnerLabel  matlab.ui.control.Label
        CheckBox_1                      matlab.ui.control.CheckBox
        ComputerArchitectureSpinnerLabel  matlab.ui.control.Label
        EmbeddedSystemSpinner           matlab.ui.control.Spinner
        EmbeddedSystemSpinnerLabel      matlab.ui.control.Label
        NetworkingSpinner               matlab.ui.control.Spinner
        NetworkingSpinnerLabel          matlab.ui.control.Label
        SecuritySpinner                 matlab.ui.control.Spinner
        SecuritySpinnerLabel            matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        CheckBox_5                      matlab.ui.control.CheckBox
        CheckBox_4                      matlab.ui.control.CheckBox
        CheckBox_3                      matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        HeterogeneousPipelinesinEmbeddedProcessorsLabel  matlab.ui.control.Label
        MachineLearningandBigDataLabel  matlab.ui.control.Label
        ArchitecturalSupportforReliabilityandSecurityLabel  matlab.ui.control.Label
        SecureMicrokernelsforEmbeddedSystemsLabel  matlab.ui.control.Label
        ComputerNetworkingandWirelessCommunicationLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            
            fis = readfis('mid');
            
            available = [];
            interests = zeros(1,10);
            
            if(app.CheckBox_1.Value == true)
                available(end+1,1) = 1;
            end              
            if(app.CheckBox_2.Value == true)
                available(end+1,1) = 2;
            end
            if(app.CheckBox_3.Value == true)
                available(end+1,1) = 3;
            end
            if(app.CheckBox_4.Value == true)
                available(end+1,1) = 4;
            end
            if(app.CheckBox_5.Value == true)
                available(end+1,1) = 5;
            end
            if(app.CheckBox_6.Value == true)
                available(end+1,1) = 6;
            end
            if(app.CheckBox_7.Value == true)
                available(end+1,1) = 7;
            end
            if(app.CheckBox_8.Value == true)
                available(end+1,1) = 8;
            end
            
            topics = ["Computer Networking and Wireless Communication", "Secure microkernels for embedded systems", "Architectural support for Reliability and Security", "Machine Learning and Big Data", "IoT", "Heterogeneous Pipelines in Embedded Processors", "Image Processing", "Cloud Computing"];                  
            
            interests(1) = app.SecuritySpinner.Value;
            interests(2) = app.NetworkingSpinner.Value;
            interests(3) = app.EmbeddedSystemSpinner.Value;
            interests(4) = app.LowLevelProgrammingSpinner.Value;
            interests(5) = app.ComputerArchitectureSpinner.Value;
            interests(6) = app.MachineLearningSpinner.Value;
            interests(7) = app.HighLevelProgrammingSpinner.Value;
            interests(8) = app.ImageProcessingSpinner.Value;
            interests(9) = app.SignalProcessingSpinner.Value;
            interests(10) = app.DevopsSpinner.Value;
           
            out = evalfis(interests,fis) * 10;
            outAvailable = zeros(1,length(available));
            topicsAvailable = strings(1,length(available));
           
            for r = 1:length(available)
                k = available(r);
                outAvailable(r) = out(k);
                topicsAvailable(r) = topics(k);
            end

            res =  [topicsAvailable.' outAvailable.'];
            app.UITable.Data = res;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Research Area'; 'Recomendation'};
            app.UITable.RowName = {};
            app.ComputerNetworkingandWirelessCommunicationLabel = uilabel(app.UIFigure);
            app.ComputerNetworkingandWirelessCommunicationLabel.Text = 'Computer Networking and Wireless Communication';
            app.SecureMicrokernelsforEmbeddedSystemsLabel = uilabel(app.UIFigure);
            app.SecureMicrokernelsforEmbeddedSystemsLabel.Text = 'Secure Microkernels for Embedded Systems';
            app.ArchitecturalSupportforReliabilityandSecurityLabel = uilabel(app.UIFigure);
            app.ArchitecturalSupportforReliabilityandSecurityLabel.Text = 'Architectural Support for Reliability and Security';
            app.MachineLearningandBigDataLabel = uilabel(app.UIFigure);
            app.MachineLearningandBigDataLabel.Text = 'Machine Learning and Big Data';
            app.HeterogeneousPipelinesinEmbeddedProcessorsLabel = uilabel(app.UIFigure);
            app.HeterogeneousPipelinesinEmbeddedProcessorsLabel.Text = 'Heterogeneous Pipelines in Embedded Processors';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'submitButton.png';
            app.Button.Text = '';
            app.SecuritySpinnerLabel = uilabel(app.UIFigure);
            app.SecuritySpinnerLabel.Text = 'Security';
            app.SecuritySpinner = uispinner(app.UIFigure);
            app.SecuritySpinner.Limits = [0 5];
            app.NetworkingSpinnerLabel = uilabel(app.UIFigure);
            app.NetworkingSpinnerLabel.Text = 'Networking';
            app.NetworkingSpinner = uispinner(app.UIFigure);
            app.NetworkingSpinner.Limits = [0 5];
            app.EmbeddedSystemSpinnerLabel = uilabel(app.UIFigure);
            app.EmbeddedSystemSpinnerLabel.Text = 'Embedded System';
            app.EmbeddedSystemSpinner = uispinner(app.UIFigure);
            app.EmbeddedSystemSpinner.Limits = [0 5];
            app.ComputerArchitectureSpinnerLabel = uilabel(app.UIFigure);
            app.ComputerArchitectureSpinnerLabel.Text = 'Computer Architecture';
            app.CheckBox_1 = uicheckbox(app.UIFigure);
            app.CheckBox_1.Text = '';
            app.LowLevelProgrammingSpinnerLabel = uilabel(app.UIFigure);
            app.LowLevelProgrammingSpinnerLabel.Text = 'Low Level Programming';
            app.LowLevelProgrammingSpinner = uispinner(app.UIFigure);
            app.LowLevelProgrammingSpinner.Limits = [0 5];
            app.ComputerArchitectureSpinner = uispinner(app.UIFigure);
            app.ComputerArchitectureSpinner.Limits = [0 5];
            app.HighLevelProgrammingSpinner = uispinner(app.UIFigure);
            app.HighLevelProgrammingSpinner.Limits = [0 5];
            app.HighLevelProgrammingSpinnerLabel = uilabel(app.UIFigure);
            app.HighLevelProgrammingSpinnerLabel.Text = 'High Level Programming';
            app.MachineLearningSpinnerLabel = uilabel(app.UIFigure);
            app.MachineLearningSpinnerLabel.Text = 'Machine Learning';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'header.jpg';
            app.MachineLearningSpinner = uispinner(app.UIFigure);
            app.MachineLearningSpinner.Limits = [0 5];
            app.AvailableResearchAreasLabel_2 = uilabel(app.UIFigure);
            app.AvailableResearchAreasLabel_2.Text = 'Available Research Areas';
            app.InterestsLabel_2 = uilabel(app.UIFigure);
            app.InterestsLabel_2.Text = 'Interests';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'security.png';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'networking.png';
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageSource = 'embeded.png';
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageSource = 'coding.png';
            app.Image2_5 = uiimage(app.UIFigure);
            app.Image2_5.ImageSource = 'architecture.png';
            app.Image2_6 = uiimage(app.UIFigure);
            app.Image2_6.ImageSource = 'ml.png';
            app.Image2_7 = uiimage(app.UIFigure);
            app.Image2_7.ImageSource = 'programing.png';
            app.ImageProcessingLabel = uilabel(app.UIFigure);
            app.ImageProcessingLabel.Text = 'Image Processing';
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.Text = '';
            app.CloudComputingLabel = uilabel(app.UIFigure);
            app.CloudComputingLabel.Text = 'Cloud Computing';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.Text = '';
            app.Image2_8 = uiimage(app.UIFigure);
            app.Image2_8.ImageSource = 'imageprocessing.png';
            app.ImageProcessingSpinner = uispinner(app.UIFigure);
            app.ImageProcessingSpinner.Limits = [0 5];
            app.ImageProcessingSpinnerLabel = uilabel(app.UIFigure);
            app.ImageProcessingSpinnerLabel.Text = 'Image Processing';
            app.Image2_9 = uiimage(app.UIFigure);
            app.Image2_9.ImageSource = 'signal.png';
            app.SignalProcessingSpinner = uispinner(app.UIFigure);
            app.SignalProcessingSpinner.Limits = [0 5];
            app.SignalProcessingSpinnerLabel = uilabel(app.UIFigure);
            app.SignalProcessingSpinnerLabel.Text = 'Signal Processing';
            app.Image2_10 = uiimage(app.UIFigure);
            app.Image2_10.ImageSource = 'devops.png';
            app.DevopsSpinner = uispinner(app.UIFigure);
            app.DevopsSpinner.Limits = [0 5];
            app.DevopsSpinnerLabel = uilabel(app.UIFigure);
            app.DevopsSpinnerLabel.Text = 'Devops';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = recomendSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end